classdef AppSegmentation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        BrowseButton         matlab.ui.control.Button
        DRSegmentationLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(comp, event)
            % Define the URL and destination folder
            pretrainedURL = "https://ssd.mathworks.com/supportfiles/vision/data/deeplabv3plusResnet18CamVid_v2.zip";
            pretrainedFolder = fullfile(tempdir, "pretrainedNetwork");
            pretrainedNetworkZip = fullfile(pretrainedFolder, "deeplabv3plusResnet18CamVid_v2.zip");
            
            % Download the pretrained network if it doesn't exist
            if ~exist(pretrainedNetworkZip, 'file')
                mkdir(pretrainedFolder);
                disp("Downloading pretrained DeepLab v3+ model (58 MB)...");
                websave(pretrainedNetworkZip, pretrainedURL);
            end
            
            % Unzip the downloaded file
            unzip(pretrainedNetworkZip, pretrainedFolder);
            
            % Load the pretrained network
            pretrainedNetwork = fullfile(pretrainedFolder, "deeplabv3plusResnet18CamVid_v2.mat");
            data = load(pretrainedNetwork);
            net = data.net;
            [filename, pathname] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp','Image Files (*.png, *.jpg, *.jpeg, *.bmp)'}, ...
                                 'Select an Image for Segmentation');
            if isequal(filename,0)
                disp('User canceled file selection.');
                return;
            end
            imgPath = fullfile(pathname, filename);
            img = imread(imgPath);
            comp.Image.ImageSource=img;

            inputSize = net.Layers(1).InputSize(1:2);
            imgResized = imresize(img, inputSize); 
            C = semanticseg(imgResized, net);
            B = labeloverlay(imgResized, C);
            comp.Image2.ImageSource=B;

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 501 444];
            comp.DRSegmentationLabel = uilabel(comp);
            comp.DRSegmentationLabel.Text = 'DR Segmentation';
            comp.BrowseButton = uibutton(comp, 'push');
            comp.BrowseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @BrowseButtonPushed, true);
            comp.BrowseButton.Text = 'Browse';
            comp.Image = uiimage(comp);
            comp.Image2 = uiimage(comp);
        end
    end
end